classdef app1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RightPanel         matlab.ui.container.Panel
        GridLayout3        matlab.ui.container.GridLayout
        LinkDifferentialDriveMobileManipulatorLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        LeftPanel          matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        PathDropDown       matlab.ui.control.DropDown
        PathDropDownLabel  matlab.ui.control.Label
        L2EditField        matlab.ui.control.NumericEditField
        L2EditFieldLabel   matlab.ui.control.Label
        L1EditField        matlab.ui.control.NumericEditField
        L1EditFieldLabel   matlab.ui.control.Label
        RunButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % general parameters            
            l1 = app.L1EditField.Value; 
            l2 = app.L2EditField.Value;  
            T = 20;
            w = 2*pi / T; % angular velocity
            S1 = 2.5;
            S2 = 0.5;
            tstep = 0.1;
            time_vec = 0:tstep:3*T;
            
            if app.PathDropDown.Value == "Ellipse"
                %% Ellipse trajectory
            
                % ellipse trajectory desired position and velocity 
                xd_e = 0.5 + S1*cos(pi/3)*cos(w*time_vec) - S2*sin(pi/3)*sin(w*time_vec);
                yd_e = 0.5 + S1*sin(pi/3)*cos(w*time_vec) + S2*cos(pi/3)*sin(w*time_vec);
                xddot_e = -S1*cos(pi/3)*w*sin(w*time_vec) -S2*sin(pi/3)*w*cos(w*time_vec);
                yddot_e = -S1*sin(pi/3)*w*sin(w*time_vec) +S2*cos(pi/3)*w*cos(w*time_vec);
                
                
                % initial joint values: assume phi = theta1 = theta2 = 0
                th_ic_e = [xd_e(1) - l1 - l2; yd_e(1); 0; 0; 0]; % x, y, phi, theta1, theta2
                
                % integrate angles using ODE45
                [t, Th] = ode15s(@(t, th)openLoopJointSpaceControl(t, th, xddot_e,yddot_e, l1, l2, tstep), time_vec, th_ic_e);
                
                % compute actual end-effector trajectory 
                % xe = x + l1*cos(phi+theta1) + l2*cos(phi+theta1+theta2)
                x_act_e = Th(:,1) + l1 * cos(Th(:,3) + Th(:,4)) + l2 * cos(Th(:,3) + Th(:,4) + Th(:,5)); 
                % ye = y + l1*sin(phi+theta1) + l2*sin(phi+theta1+theta2)
                y_act_e = Th(:,2) + l1 * sin(Th(:,3) + Th(:,4)) + l2 * sin(Th(:,3) + Th(:,4) + Th(:,5)); 
                
                
                % draw animation
                daspect(app.UIAxes, [1 1 1]);
                xlim(app.UIAxes, [-5 5]);
                ylim(app.UIAxes, [-5 5]);
                for i=1:length(time_vec) 
                    
                    cla(app.UIAxes);
    
                    % plot desired EE trajectory 
                    plot(app.UIAxes, xd_e, yd_e,'Color','blue'); 
                    % plot end effector pos
                    plot(app.UIAxes, x_act_e(i), y_act_e(i), 'o','Color','blue'); 
                    hold(app.UIAxes,'on');
                    % plot base center
                    plot(app.UIAxes, Th(i, 1), Th(i, 2), 'o','Color','black');
                    % plot base forward direction 
                    fdirx = [Th(i, 1) Th(i, 1)+cos(Th(i, 3))];
                    fdiry = [Th(i, 2) Th(i, 2)+sin(Th(i, 3))];
                    plot(app.UIAxes, fdirx, fdiry,'color', "red", 'LineWidth', 3);
                
                    % plot links
                    link1x = [Th(i, 1) Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4))];
                    link1y = [Th(i, 2) Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4))];
                    link2x = [Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4)) Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4))+l2*cos(Th(i, 3)+Th(i, 4)+Th(i, 5))];
                    link2y = [Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4)) Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4))+l2*sin(Th(i, 3)+Th(i, 4)+Th(i, 5))];
                    plot(app.UIAxes, link1x, link1y,'color', "#0072BD", 'LineWidth', 1);
                    plot(app.UIAxes, link2x, link2y,'color', "#D95319", 'LineWidth', 1);
    
                    drawnow;
                end
            elseif app.PathDropDown.Value == "Square"

                % square trajectory 
                time_vec_side_normalized = 0:tstep/(T/4):1;
                time_vec_side_normalized(end)=[]; % remove last item to avoid corner duplication 
                side = 3.5;
                side_min = 1 - side / 2;
                side_max = 1 + side / 2;
                % POSITION
                % bottom side
                xd_s = side_min + time_vec_side_normalized * (side_max - side_min);
                yd_s = side_min + time_vec_side_normalized * 0;
                % right side 
                xd_s = [xd_s, side_max + time_vec_side_normalized * 0];
                yd_s = [yd_s, side_min + time_vec_side_normalized * (side_max - side_min)];
                % top side
                xd_s = [xd_s, side_max - time_vec_side_normalized * (side_max - side_min)];
                yd_s = [yd_s, side_max + time_vec_side_normalized * 0]; 
                % left side 
                xd_s = [xd_s, side_min + time_vec_side_normalized * 0];
                yd_s = [yd_s, side_max - time_vec_side_normalized * (side_max - side_min)];
                
                step_vel = (xd_s(2) - xd_s(1)) / tstep;
                % VELOCITY
                % bottom side 
                xddot_s = step_vel * ones(1, length(time_vec_side_normalized));
                yddot_s = zeros(1, length(time_vec_side_normalized));
                % right side 
                xddot_s = [xddot_s, zeros(1, length(time_vec_side_normalized))];
                yddot_s = [yddot_s, step_vel * ones(1, length(time_vec_side_normalized))];
                % top side
                xddot_s = [xddot_s, -step_vel * ones(1, length(time_vec_side_normalized))];
                yddot_s = [yddot_s, zeros(1, length(time_vec_side_normalized))];
                % left side 
                xddot_s = [xddot_s, zeros(1, length(time_vec_side_normalized))];
                yddot_s = [yddot_s, -step_vel * ones(1, length(time_vec_side_normalized))];
                
                % three cycles 
                xd_s = [xd_s, xd_s, xd_s];
                yd_s = [yd_s, yd_s, yd_s];
                xddot_s = [xddot_s, xddot_s, xddot_s];
                yddot_s = [yddot_s, yddot_s, yddot_s];
                % add last corner for size consistency with time_vec
                xd_s = [xd_s, xd_s(1)];
                yd_s = [yd_s, yd_s(1)];
                xddot_s = [xddot_s, xddot_s(1)];
                yddot_s = [yddot_s, yddot_s(1)];
                
                
                % initial joint values: assume phi = theta1 = theta2 = 0
                th_ic_s = [xd_s(1) - l1 - l2; yd_s(1); 0; 0; 0]; % x, y, phi, theta1, theta2
                
                
                % integrate angles using ODE45
                [t, Th] = ode15s(@(t, th)openLoopJointSpaceControl(t, th, xddot_s,yddot_s, l1, l2, tstep), time_vec, th_ic_s);
                
                % compute actual end-effector trajectory 
                % xe = x + l1*cos(phi+theta1) + l2*cos(phi+theta1+theta2)
                x_act_s = Th(:,1) + l1 * cos(Th(:,3) + Th(:,4)) + l2 * cos(Th(:,3) + Th(:,4) + Th(:,5)); 
                % ye = y + l1*sin(phi+theta1) + l2*sin(phi+theta1+theta2)
                y_act_s = Th(:,2) + l1 * sin(Th(:,3) + Th(:,4)) + l2 * sin(Th(:,3) + Th(:,4) + Th(:,5)); 
                
                % draw animation
                daspect(app.UIAxes, [1 1 1]);
                xlim(app.UIAxes, [-5 5]);
                ylim(app.UIAxes, [-5 5]);
                for i=1:length(x_act_s) 
                    
                    cla(app.UIAxes);
    
                    % plot desired EE trajectory 
                    plot(app.UIAxes, xd_s, yd_s,'Color','blue'); 
                    % plot end effector pos
                    plot(app.UIAxes, x_act_s(i), y_act_s(i), 'o','Color','blue'); 
                    hold(app.UIAxes,'on');
                    % plot base center
                    plot(app.UIAxes, Th(i, 1), Th(i, 2), 'o','Color','black');
                    % plot base forward direction 
                    fdirx = [Th(i, 1) Th(i, 1)+cos(Th(i, 3))];
                    fdiry = [Th(i, 2) Th(i, 2)+sin(Th(i, 3))];
                    plot(app.UIAxes, fdirx, fdiry,'color', "red", 'LineWidth', 3);
                
                    % plot links
                    link1x = [Th(i, 1) Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4))];
                    link1y = [Th(i, 2) Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4))];
                    link2x = [Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4)) Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4))+l2*cos(Th(i, 3)+Th(i, 4)+Th(i, 5))];
                    link2y = [Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4)) Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4))+l2*sin(Th(i, 3)+Th(i, 4)+Th(i, 5))];
                    plot(app.UIAxes, link1x, link1y,'color', "#0072BD", 'LineWidth', 1);
                    plot(app.UIAxes, link2x, link2y,'color', "#D95319", 'LineWidth', 1);
    
                    drawnow;
                end

            end


            function dth = openLoopJointSpaceControl(t, th, xddot, yddot, l1, l2, tstep)
            
                % compute Jacobian
                Jp = [1, 0, -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l2*sin(th(3)+th(4)+th(5));
                      0, 1, l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l2*cos(th(3)+th(4)+th(5));     
                      -sin(th(3)), cos(th(3)), 0, 0, 0];
            
                % compute open-loop theta dot
                t_idx = 1+int32(t/tstep);
                dth_openloop = pinv(Jp) * [xddot(t_idx); yddot(t_idx); 0]; 
                
                % no feedback
                dth = dth_openloop;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.RunButton = uibutton(app.GridLayout2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.L1EditFieldLabel = uilabel(app.GridLayout2);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.L1EditField.Value = 2.5;
            app.L2EditFieldLabel = uilabel(app.GridLayout2);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.L2EditField.Value = 1.5;
            app.PathDropDownLabel = uilabel(app.GridLayout2);
            app.PathDropDownLabel.Text = 'Path';
            app.PathDropDown = uidropdown(app.GridLayout2);
            app.PathDropDown.Items = {'Ellipse', 'Square'};
            app.PathDropDown.Value = 'Ellipse';
            app.RightPanel = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.LinkDifferentialDriveMobileManipulatorLabel = uilabel(app.GridLayout3);
            app.LinkDifferentialDriveMobileManipulatorLabel.Text = '2-Link Differential Drive Mobile Manipulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end