classdef robot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        ControlModeDropDown       matlab.ui.control.DropDown
        ControlModeDropDownLabel  matlab.ui.control.Label
        PathDropDown              matlab.ui.control.DropDown
        PathDropDownLabel         matlab.ui.control.Label
        L2EditField               matlab.ui.control.NumericEditField
        L2EditFieldLabel          matlab.ui.control.Label
        L1EditField               matlab.ui.control.NumericEditField
        L1EditFieldLabel          matlab.ui.control.Label
        RunButton                 matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % general parameters            
            l1 = app.L1EditField.Value; 
            l2 = app.L2EditField.Value;  
            T = 20;
            w = 2*pi / T; % angular velocity
            S1 = 2.5;
            S2 = 0.5;
            tstep = 0.1;
            time_vec = 0:tstep:3*T;
            
            if app.PathDropDown.Value == "Ellipse"
                %% Ellipse trajectory
            
                % ellipse trajectory desired position and velocity 
                xd_e = 0.5 + S1*cos(pi/3)*cos(w*time_vec) - S2*sin(pi/3)*sin(w*time_vec);
                yd_e = 0.5 + S1*sin(pi/3)*cos(w*time_vec) + S2*cos(pi/3)*sin(w*time_vec);
                xddot_e = -S1*cos(pi/3)*w*sin(w*time_vec) -S2*sin(pi/3)*w*cos(w*time_vec);
                yddot_e = -S1*sin(pi/3)*w*sin(w*time_vec) +S2*cos(pi/3)*w*cos(w*time_vec);
                
                
                % initial joint values: assume phi = theta1 = theta2 = 0
                th_ic_e = [xd_e(1) - l1 - l2; yd_e(1); 0; 0; 0]; % x, y, phi, theta1, theta2
                
                % integrate angles using ODE function
                if app.ControlModeDropDown.Value == "Pseudoinverse"
                    [t, Th] = ode15s(@(t, th)openLoopControlPseudoinverse(t, th, xddot_e,yddot_e, l1, l2, tstep), time_vec, th_ic_e);
                elseif app.ControlModeDropDown.Value == "Constraint 1"
                    [t, Th] = ode15s(@(t, th)openLoopControlConstraint1(t, th, xddot_e,yddot_e, l1, l2, tstep), time_vec, th_ic_e);
                elseif app.ControlModeDropDown.Value == "Constraint 2"
                    [t, Th] = ode15s(@(t, th)openLoopControlConstraint2(t, th, xddot_e,yddot_e, l1, l2, tstep), time_vec, th_ic_e);
                elseif app.ControlModeDropDown.Value == "Potential"
                    [t, Th] = ode15s(@(t, th)openLoopControlPotential(t, th, xddot_e,yddot_e, l1, l2, tstep, 100), time_vec, th_ic_e); % k = 100
                elseif app.ControlModeDropDown.Value == "Feedback"
                    [t, Th] = ode15s(@(t, th)closedLoopTaskSpaceControl(t, th, xddot_e,yddot_e, xd_e, yd_e, l1, l2, tstep), time_vec, th_ic_e); 
                end

                % compute actual end-effector trajectory 
                % xe = x + l1*cos(phi+theta1) + l2*cos(phi+theta1+theta2)
                x_act_e = Th(:,1) + l1 * cos(Th(:,3) + Th(:,4)) + l2 * cos(Th(:,3) + Th(:,4) + Th(:,5)); 
                % ye = y + l1*sin(phi+theta1) + l2*sin(phi+theta1+theta2)
                y_act_e = Th(:,2) + l1 * sin(Th(:,3) + Th(:,4)) + l2 * sin(Th(:,3) + Th(:,4) + Th(:,5)); 
                
                
                % draw animation
                daspect(app.UIAxes, [1 1 1]);
                xlim(app.UIAxes, [-7 7]);
                ylim(app.UIAxes, [-4 4]);
                cImage = imread("robot.png");
                for i=1:length(x_act_e) 
                    cla(app.UIAxes);

                    % plot end effector pos
                    plot(app.UIAxes, x_act_e(i), y_act_e(i), 'o','Color','blue'); 
                    hold(app.UIAxes,'on');  
  
                    % draw robot
                    cImageRotated = imrotate(255-cImage,rad2deg(Th(i, 3)),"nearest","crop");
                    image(flipud(255-cImageRotated), 'XData', [Th(i, 1)-0.7 Th(i, 1)+0.7], 'YData', [Th(i, 2)-0.7 Th(i, 2)+0.7], 'Parent', app.UIAxes);
                    
                    % plot base forward direction 
                    fdirx = [Th(i, 1) Th(i, 1)+0.4*cos(Th(i, 3))];
                    fdiry = [Th(i, 2) Th(i, 2)+0.4*sin(Th(i, 3))];
                    plot(app.UIAxes, fdirx, fdiry,'color', "black", 'LineWidth', 5);

                    % plot desired EE trajectory 
                    plot(app.UIAxes, xd_e, yd_e,'color','#a2eeff', 'LineWidth', 2); 
                
                    % plot links
                    link1x = [Th(i, 1) Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4))];
                    link1y = [Th(i, 2) Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4))];
                    link2x = [Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4)) Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4))+l2*cos(Th(i, 3)+Th(i, 4)+Th(i, 5))];
                    link2y = [Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4)) Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4))+l2*sin(Th(i, 3)+Th(i, 4)+Th(i, 5))];
                    plot(app.UIAxes, link1x, link1y,'color', "#ff9b8c", 'LineWidth', 4);
                    plot(app.UIAxes, link2x, link2y,'color', "#d4f49d", 'LineWidth', 4);
    
                    drawnow;
                end
            elseif app.PathDropDown.Value == "Square"

                % square trajectory 
                time_vec_side_normalized = 0:tstep/(T/4):1;
                time_vec_side_normalized(end)=[]; % remove last item to avoid corner duplication 
                side = 3.5;
                side_min = 1 - side / 2;
                side_max = 1 + side / 2;
                % POSITION
                % bottom side
                xd_s = side_min + time_vec_side_normalized * (side_max - side_min);
                yd_s = side_min + time_vec_side_normalized * 0;
                % right side 
                xd_s = [xd_s, side_max + time_vec_side_normalized * 0];
                yd_s = [yd_s, side_min + time_vec_side_normalized * (side_max - side_min)];
                % top side
                xd_s = [xd_s, side_max - time_vec_side_normalized * (side_max - side_min)];
                yd_s = [yd_s, side_max + time_vec_side_normalized * 0]; 
                % left side 
                xd_s = [xd_s, side_min + time_vec_side_normalized * 0];
                yd_s = [yd_s, side_max - time_vec_side_normalized * (side_max - side_min)];
                
                step_vel = (xd_s(2) - xd_s(1)) / tstep;
                % VELOCITY
                % bottom side 
                xddot_s = step_vel * ones(1, length(time_vec_side_normalized));
                yddot_s = zeros(1, length(time_vec_side_normalized));
                % right side 
                xddot_s = [xddot_s, zeros(1, length(time_vec_side_normalized))];
                yddot_s = [yddot_s, step_vel * ones(1, length(time_vec_side_normalized))];
                % top side
                xddot_s = [xddot_s, -step_vel * ones(1, length(time_vec_side_normalized))];
                yddot_s = [yddot_s, zeros(1, length(time_vec_side_normalized))];
                % left side 
                xddot_s = [xddot_s, zeros(1, length(time_vec_side_normalized))];
                yddot_s = [yddot_s, -step_vel * ones(1, length(time_vec_side_normalized))];
                
                % three cycles 
                xd_s = [xd_s, xd_s, xd_s];
                yd_s = [yd_s, yd_s, yd_s];
                xddot_s = [xddot_s, xddot_s, xddot_s];
                yddot_s = [yddot_s, yddot_s, yddot_s];
                % add last corner for size consistency with time_vec
                xd_s = [xd_s, xd_s(1)];
                yd_s = [yd_s, yd_s(1)];
                xddot_s = [xddot_s, xddot_s(1)];
                yddot_s = [yddot_s, yddot_s(1)];
                
                
                % initial joint values: assume phi = theta1 = theta2 = 0
                th_ic_s = [xd_s(1) - l1 - l2; yd_s(1); 0; 0; 0]; % x, y, phi, theta1, theta2
                
                
                % integrate angles using ODE function
                if app.ControlModeDropDown.Value == "Pseudoinverse"
                    [t, Th] = ode15s(@(t, th)openLoopControlPseudoinverse(t, th, xddot_s,yddot_s, l1, l2, tstep), time_vec, th_ic_s);
                elseif app.ControlModeDropDown.Value == "Constraint 1"
                    [t, Th] = ode15s(@(t, th)openLoopControlConstraint1(t, th, xddot_s,yddot_s, l1, l2, tstep), time_vec, th_ic_s);
                elseif app.ControlModeDropDown.Value == "Constraint 2"
                    [t, Th] = ode15s(@(t, th)openLoopControlConstraint2(t, th, xddot_s,yddot_s, l1, l2, tstep), time_vec, th_ic_s);
                elseif app.ControlModeDropDown.Value == "Potential"
                    [t, Th] = ode15s(@(t, th)openLoopControlPotential(t, th, xddot_s,yddot_s, l1, l2, tstep, 100), time_vec, th_ic_s); % k = 100
                elseif app.ControlModeDropDown.Value == "Feedback"
                    [t, Th] = ode15s(@(t, th)closedLoopTaskSpaceControl(t, th, xddot_s,yddot_s, xd_s, yd_s, l1, l2, tstep), time_vec, th_ic_s); 
                end

                % compute actual end-effector trajectory 
                % xe = x + l1*cos(phi+theta1) + l2*cos(phi+theta1+theta2)
                x_act_s = Th(:,1) + l1 * cos(Th(:,3) + Th(:,4)) + l2 * cos(Th(:,3) + Th(:,4) + Th(:,5)); 
                % ye = y + l1*sin(phi+theta1) + l2*sin(phi+theta1+theta2)
                y_act_s = Th(:,2) + l1 * sin(Th(:,3) + Th(:,4)) + l2 * sin(Th(:,3) + Th(:,4) + Th(:,5)); 
                
                % draw animation
                daspect(app.UIAxes, [1 1 1]);
                xlim(app.UIAxes, [-7 7]);
                ylim(app.UIAxes, [-4 4]);
                cImage = imread("robot.png");
                for i=1:length(x_act_s) 
                    cla(app.UIAxes);

                    % plot end effector pos
                    plot(app.UIAxes, x_act_s(i), y_act_s(i), 'o','Color','blue'); 
                    hold(app.UIAxes,'on');  
  
                    % draw robot
                    cImageRotated = imrotate(255-cImage,rad2deg(Th(i, 3)),"nearest","crop");
                    image(flipud(255-cImageRotated), 'XData', [Th(i, 1)-0.7 Th(i, 1)+0.7], 'YData', [Th(i, 2)-0.7 Th(i, 2)+0.7], 'Parent', app.UIAxes);
                    
                    % plot base forward direction 
                    fdirx = [Th(i, 1) Th(i, 1)+0.4*cos(Th(i, 3))];
                    fdiry = [Th(i, 2) Th(i, 2)+0.4*sin(Th(i, 3))];
                    plot(app.UIAxes, fdirx, fdiry,'color', "black", 'LineWidth', 5);

                    % plot desired EE trajectory 
                    plot(app.UIAxes, xd_s, yd_s,'color','#a2eeff', 'LineWidth', 2); 
                
                    % plot links
                    link1x = [Th(i, 1) Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4))];
                    link1y = [Th(i, 2) Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4))];
                    link2x = [Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4)) Th(i, 1)+l1*cos(Th(i, 3)+Th(i, 4))+l2*cos(Th(i, 3)+Th(i, 4)+Th(i, 5))];
                    link2y = [Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4)) Th(i, 2)+l1*sin(Th(i, 3)+Th(i, 4))+l2*sin(Th(i, 3)+Th(i, 4)+Th(i, 5))];
                    plot(app.UIAxes, link1x, link1y,'color', "#ff9b8c", 'LineWidth', 4);
                    plot(app.UIAxes, link2x, link2y,'color', "#d4f49d", 'LineWidth', 4);
    
                    drawnow;
                end

            end


            function dth = openLoopControlPseudoinverse(t, th, xddot, yddot, l1, l2, tstep)
            
                % compute Jacobian
                Jp = [1, 0, -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l2*sin(th(3)+th(4)+th(5));
                      0, 1, l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l2*cos(th(3)+th(4)+th(5));     
                      -sin(th(3)), cos(th(3)), 0, 0, 0];
            
                % compute open-loop theta dot
                t_idx = 1+int32(t/tstep);
                dth_openloop = pinv(Jp) * [xddot(t_idx); yddot(t_idx); 0]; 
                
                % no feedback
                dth = dth_openloop;
            end

            function dth = openLoopControlConstraint1(t, th, xddot, yddot, l1, l2, tstep)
            
                % compute Jacobian with added constraint: theta1dot + theta2dot = 0
                Jp = [1, 0, -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l2*sin(th(3)+th(4)+th(5));
                      0, 1, l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l2*cos(th(3)+th(4)+th(5));     
                      -sin(th(3)), cos(th(3)), 0, 0, 0;
                      0, 0, 0, 1, 1];
            
                % compute open-loop theta dot
                t_idx = 1+int32(t/tstep);
                dth_openloop = pinv(Jp) * [xddot(t_idx); yddot(t_idx); 0; 0]; 
                
                % no feedback
                dth = dth_openloop;
            end

            function dth = openLoopControlConstraint2(t, th, xddot, yddot, l1, l2, tstep)
            
                % compute Jacobian with added constraint: theta1dot = 0
                Jp = [1, 0, -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l2*sin(th(3)+th(4)+th(5));
                      0, 1, l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l2*cos(th(3)+th(4)+th(5));     
                      -sin(th(3)), cos(th(3)), 0, 0, 0;
                      0, 0, 0, 1, 0];
            
                % compute open-loop theta dot
                t_idx = 1+int32(t/tstep);
                dth_openloop = pinv(Jp) * [xddot(t_idx); yddot(t_idx); 0; 0]; 
                
                % no feedback
                dth = dth_openloop;
            end

            function dth = openLoopControlPotential(t, th, xddot, yddot, l1, l2, tstep, K)
            
            
                % compute Jacobian 
                J = [1, 0, -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l2*sin(th(3)+th(4)+th(5));
                     0, 1, l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l2*cos(th(3)+th(4)+th(5));     
                     -sin(th(3)), cos(th(3)), 0, 0, 0];
                Jpinv = pinv(J);
                
                % gradient of potential V with respect to x, y, phi, theta1, theta2
                qz = [0; 0; 1.32*th(3); 2*th(4); 0.5*th(5)];
            
                % compute open-loop theta dot
                t_idx = 1+int32(t/tstep);
                dth_openloop = Jpinv * [xddot(t_idx); yddot(t_idx); 0] + (eye(5) - Jpinv*J)*(-K)*qz; 
                
                % no feedback
                dth = dth_openloop;
            end
          
            function dth = closedLoopTaskSpaceControl(t, th, xddot, yddot, xd, yd, l1, l2, tstep)
        
                    K = [5 0 0;
                         0 10 0;
                         0 0 0];
                % current time step
                t_idx = 1+int32(t/tstep);
            
                % compute Jacobian
                Jp = [1, 0, -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l1*sin(th(3)+th(4))-l2*sin(th(3)+th(4)+th(5)), -l2*sin(th(3)+th(4)+th(5));
                      0, 1, l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l1*cos(th(3)+th(4))+l2*cos(th(3)+th(4)+th(5)), l2*cos(th(3)+th(4)+th(5));     
                      -sin(th(3)), cos(th(3)), 0, 0, 0];
                
                % end-effector actual pose (forward pos. kinematics)
                xy_ee_actual = [th(1) + l1*cos(th(3)+th(4)) + l2*cos(th(3)+th(4)+th(5)); % x + l1*cos(phi+theta1) + l2*cos(phi+theta1+theta2)
                    th(2) + l1*sin(th(3)+th(4)) + l2*sin(th(3)+th(4)+th(5)); % y + l1*sin(phi+theta1) + l2*sin(phi+theta1+theta2)
                    0]; 
                
                
                % apply feedback 
                xy_ee_feedback = [xddot(t_idx); yddot(t_idx); 0] + K*([xd(t_idx);yd(t_idx);0]-xy_ee_actual);
            
                % compute joints 
                dth = pinv(Jp) * xy_ee_feedback; 
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {441, 441};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {229, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.RunButton = uibutton(app.GridLayout4, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.L1EditFieldLabel = uilabel(app.GridLayout4);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.L1EditField.Value = 2.5;
            app.L2EditFieldLabel = uilabel(app.GridLayout4);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.L2EditField.Value = 2;
            app.PathDropDownLabel = uilabel(app.GridLayout4);
            app.PathDropDownLabel.Text = 'Path';
            app.PathDropDown = uidropdown(app.GridLayout4);
            app.PathDropDown.Items = {'Ellipse', 'Square'};
            app.PathDropDown.Value = 'Ellipse';
            app.ControlModeDropDownLabel = uilabel(app.GridLayout4);
            app.ControlModeDropDownLabel.Text = 'Control Mode';
            app.ControlModeDropDown = uidropdown(app.GridLayout4);
            app.ControlModeDropDown.Items = {'Pseudoinverse', 'Constraint 1', 'Constraint 2', 'Potential', 'Feedback'};
            app.ControlModeDropDown.Value = 'Pseudoinverse';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout5);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end